classdef MainPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Panel_5                     matlab.ui.container.Panel
        DropDown_5                  matlab.ui.control.DropDown
        Panel_4                     matlab.ui.container.Panel
        DropDown_6                  matlab.ui.control.DropDown
        Panel_6                     matlab.ui.container.Panel
        Button_4                    matlab.ui.control.Button
        Panel_8                     matlab.ui.container.Panel
        DropDown_8                  matlab.ui.control.DropDown
        Tab_3                       matlab.ui.container.Tab
        Panel_10                    matlab.ui.container.Panel
        DropDown_9                  matlab.ui.control.DropDown
        Label_7                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        TextArea_4                  matlab.ui.control.TextArea
        Button_5                    matlab.ui.control.Button
        Panel_14                    matlab.ui.container.Panel
        Panel_11                    matlab.ui.container.Panel
        DropDown_10                 matlab.ui.control.DropDown
        Label_4                     matlab.ui.control.Label
        Panel_12                    matlab.ui.container.Panel
        DropDown_11                 matlab.ui.control.DropDown
        Label_5                     matlab.ui.control.Label
        Panel_13                    matlab.ui.container.Panel
        DropDown_12                 matlab.ui.control.DropDown
        Label_6                     matlab.ui.control.Label
        ExportToMatFile_2           matlab.ui.control.Button
        Tab_2                       matlab.ui.container.Tab
        Button_3                    matlab.ui.control.Button
        TrainButton                 matlab.ui.control.Button
        SplitDataPanel              matlab.ui.container.Panel
        TrainEditFieldLabel         matlab.ui.control.Label
        TrainEditField              matlab.ui.control.NumericEditField
        ValidationEditFieldLabel    matlab.ui.control.Label
        ValidationEditField         matlab.ui.control.NumericEditField
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Tree                        matlab.ui.container.Tree
        Node                        matlab.ui.container.TreeNode
        SVMNode                     matlab.ui.container.TreeNode
        RFNode                      matlab.ui.container.TreeNode
        XgboostNode                 matlab.ui.container.TreeNode
        Node_3                      matlab.ui.container.TreeNode
        TCANode                     matlab.ui.container.TreeNode
        Label_2                     matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        Button_6                    matlab.ui.control.Button
        HelpTab                     matlab.ui.container.Tab
        DocumentationAboutthisAppButton  matlab.ui.control.Button
        MathWorksDocumentationTransferLearningButton  matlab.ui.control.Button
        ExportTab                   matlab.ui.container.Tab
        MATLABCodeGenerationButton  matlab.ui.control.Button
        ExportToWorkspaceButton     matlab.ui.control.Button
        ExportToONNXButton          matlab.ui.control.Button
        GPUCoder                    matlab.ui.control.Button
        ExportToMatFile             matlab.ui.control.Button
        Panel_2                     matlab.ui.container.Panel
        StatusofRunningLampLabel    matlab.ui.control.Label
        StatusofRunningLamp         matlab.ui.control.Lamp
        TextArea_2                  matlab.ui.control.TextArea
        Panel_3                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        InsightofImportedDataPanel  matlab.ui.container.Panel
        SubjectIDLabel              matlab.ui.control.Label
        TrailLabel                  matlab.ui.control.Label
        ChannelLabel                matlab.ui.control.Label
        DurationLabel               matlab.ui.control.Label
        Label_12                    matlab.ui.control.Label
        Label_13                    matlab.ui.control.Label
        Label_14                    matlab.ui.control.Label
        Label_15                    matlab.ui.control.Label
        StateLabel                  matlab.ui.control.Label
        Label_16                    matlab.ui.control.Label
        ValidityofImportedDataLampLabel  matlab.ui.control.Label
        ValidityofImportedDataLamp  matlab.ui.control.Lamp
        InsightofPretrainedNeuralNetworkPanel  matlab.ui.container.Panel
        FirstLayerInputSizeLabel    matlab.ui.control.Label
        TypeofNetworkLabel          matlab.ui.control.Label
        NumberofLayerLabel          matlab.ui.control.Label
        NumberofClassLabel          matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        SeriesLabel                 matlab.ui.control.Label
        Label_10                    matlab.ui.control.Label
        Label_11                    matlab.ui.control.Label
        Size                        matlab.ui.control.Label
        ParametersMillionsLabel     matlab.ui.control.Label
        SizeLabel                   matlab.ui.control.Label
        parameterlabel              matlab.ui.control.Label
        Name_neuralnetwork          matlab.ui.control.Label
        neuralnetworklabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            timeDomain = TimeDomain;
            timeDomain = DataLoadFunction(timeDomain);
            for k = 1:timeDomain.subjectNum
                dropDown6Items{k} = ['成员', num2str(k)];
            end
            app.DropDown_6.Items = dropDown6Items;
            for k = 1:timeDomain.trailNum
                dropDown8Items{k} = ['实验', num2str(k)];
            end
            app.DropDown_8.Items = dropDown8Items;
            for k = 1:timeDomain.channelNum
                dropDown5Items{k} = ['通道', num2str(k)]; %#ok<*AGROW>
                if k==timeDomain.channelNum; dropDown5Items{k+1} = '所有通道'; end
            end
            app.DropDown_5.Items = dropDown5Items;
            %---在面板上显示关于选择数据的信息---
            showArea = struct('subject', app.Label_12, 'trail', app.Label_13,...
                'channel', app.Label_14, 'duration', app.Label_15, 'state',...
                app.Label_16, 'lamp', app.ValidityofImportedDataLamp);            
            timeDomain.dataDispNum = DataDisplay(timeDomain, showArea, ...
                app.DropDown_6.Value,app.DropDown_8.Value, app.DropDown_5.Value);
            plot(app.UIAxes, timeDomain.dataDispNum);
            setappdata(app.Button_4, 'TimeDomain', timeDomain);
            setappdata(app.Button_4, 'showArea', showArea);
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            timeDomain = getappdata(app.Button_4, 'TimeDomain');
            showArea = struct('subject', app.Label_12, 'trail', app.Label_13,...
                'channel', app.Label_14, 'duration', app.Label_15, 'state',...
                app.Label_16, 'lamp', app.ValidityofImportedDataLamp);
            timeDomain.dataDispNum = DataDisplay(timeDomain, showArea, ...
                app.DropDown_6.Value,app.DropDown_8.Value, app.DropDown_5.Value);
            plot(app.UIAxes, timeDomain.dataDispNum);
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            timeDomain = getappdata(app.Button_4, 'TimeDomain');
            showArea = struct('subject', app.Label_12, 'trail', app.Label_13,...
                'channel', app.Label_14, 'duration', app.Label_15, 'state',...
                app.Label_16, 'lamp', app.ValidityofImportedDataLamp);
            timeDomain.dataDispNum = DataDisplay(timeDomain, showArea, ...
                app.DropDown_6.Value,app.DropDown_8.Value, app.DropDown_5.Value);
            plot(app.UIAxes, timeDomain.dataDispNum);
        end

        % Value changed function: DropDown_8
        function DropDown_8ValueChanged(app, event)
            timeDomain = getappdata(app.Button_4, 'TimeDomain');
            showArea = struct('subject', app.Label_12, 'trail', app.Label_13,...
                'channel', app.Label_14, 'duration', app.Label_15, 'state',...
                app.Label_16, 'lamp', app.ValidityofImportedDataLamp);
            timeDomain.dataDispNum = DataDisplay(timeDomain, showArea, ...
                app.DropDown_6.Value,app.DropDown_8.Value, app.DropDown_5.Value);
            plot(app.UIAxes, timeDomain.dataDispNum);
        end

        % Callback function
        function Panel_6SizeChanged(app, event)
        
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            timeDomain = getappdata(app.Button_4, 'TimeDomain');
            for k = 1:timeDomain.subjectNum
                dropDown10Items{k} = num2str(k);
            end
            app.DropDown_10.Items = dropDown10Items;
            for k = 1:timeDomain.trailNum
                dropDown11Items{k} = num2str(k);
            end
            app.DropDown_11.Items = dropDown11Items;
            for k = 1:timeDomain.channelNum
                dropDown12Items{k} = num2str(k);
            end
            app.DropDown_12.Items = dropDown12Items;
            featureExtracObj = FeatureExtraction(timeDomain, app.TextArea_4.Value{1}, app.DropDown_9.Value);
            featureExtracObj = featureExtracObj.FeatureDisplay(featureExtracObj.featureData,...
                app.DropDown_10.Value, app.DropDown_11.Value, app.DropDown_12.Value);
            plot(app.UIAxes_2, featureExtracObj.featureDataDisp)
            setappdata(app.Button_5, 'FeatureExtraction', featureExtracObj);
        end

        % Value changed function: DropDown_10
        function DropDown_10ValueChanged(app, event)
            featureExtracObj = getappdata(app.Button_5, 'FeatureExtraction');
            featureExtracObj = featureExtracObj.FeatureDisplay(featureExtracObj.featureData,...
                app.DropDown_10.Value, app.DropDown_11.Value, app.DropDown_12.Value);
            plot(app.UIAxes_2, featureExtracObj.featureDataDisp)
        end

        % Value changed function: DropDown_11
        function DropDown_11ValueChanged(app, event)
            featureExtracObj = getappdata(app.Button_5, 'FeatureExtraction');
            featureExtracObj = featureExtracObj.FeatureDisplay(featureExtracObj.featureData,...
                app.DropDown_10.Value, app.DropDown_11.Value, app.DropDown_12.Value);
            plot(app.UIAxes_2, featureExtracObj.featureDataDisp)
        end

        % Value changed function: DropDown_12
        function DropDown_12ValueChanged(app, event)
            featureExtracObj = getappdata(app.Button_5, 'FeatureExtraction');
            featureExtracObj = featureExtracObj.FeatureDisplay(featureExtracObj.featureData,...
                app.DropDown_10.Value, app.DropDown_11.Value, app.DropDown_12.Value);
            plot(app.UIAxes_2, featureExtracObj.featureDataDisp)
        end

        % Button pushed function: ExportToMatFile_2
        function ExportToMatFile_2ButtonPushed(app, event)
            featureExtracObj = getappdata(app.Button_5, 'FeatureExtraction');
            saveData = featureExtracObj.featureData; %#ok<*NASGU>
            uisave('saveData', 'feature');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            eegClassifyObj = EEGClassify;
            eegClassifyObj = eegClassifyObj.FeatureLoad;
            setappdata(app.Button_3, 'EEGClassify', eegClassifyObj);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            eegClassifyObj = getappdata(app.Button_3, 'EEGClassify');
            eegClassifyObj = eegClassifyObj.LabelLoad;
            setappdata(app.Button_6, 'EEGClassify', eegClassifyObj);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            methodSel =  getappdata(app.ButtonGroup, 'classifyMethod');
            eegClassifyObj = getappdata(app.Button_6, 'EEGClassify');
            app.StatusofRunningLamp.Color = 'red';
            pause(0.5);
            eegClassifyObj = eegClassifyObj.FeatureClassify(app.TrainEditField.Value, methodSel);
            pause(0.5);
            app.StatusofRunningLamp.Color = 'green';
            
            fid = fopen('MyLog.txt');
            tline = fgetl(fid); i = 1;
            while ischar(tline)
                textContent{i} = sprintf('%s', tline);
                i = i+1;
                tline = fgetl(fid);
            end
            fclose(fid);
            app.TextArea_2.Value = textContent;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            methodSel = selectedNodes.Text;
            setappdata(app.ButtonGroup, 'classifyMethod', methodSel);
        end

        % Node text changed function: Tree
        function TreeNodeTextChanged(app, event)
            node = event.Node;
            methodSel = node.Text;
            setappdata(app.ButtonGroup, 'classifyMethod', methodSel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '时域信号';
            app.Panel_5 = uipanel(app.Tab);
            app.Panel_5.Title = '通道选择';
            app.DropDown_5 = uidropdown(app.Panel_5);
            app.DropDown_5.Items = {'', ''};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '';
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '被试选择';
            app.DropDown_6 = uidropdown(app.Panel_4);
            app.DropDown_6.Items = {};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = {};
            app.Panel_6 = uipanel(app.Tab);
            app.Button_4 = uibutton(app.Panel_6, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Tag = 'DataLoadButton';
            app.Button_4.Icon = 'TimeDomainData.png';
            app.Button_4.Text = '输入信号';
            app.Panel_8 = uipanel(app.Tab);
            app.Panel_8.Title = '实验选择';
            app.DropDown_8 = uidropdown(app.Panel_8);
            app.DropDown_8.Items = {};
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_8ValueChanged, true);
            app.DropDown_8.Value = {};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '特征提取';
            app.Panel_10 = uipanel(app.Tab_3);
            app.Panel_10.Title = '特征提取';
            app.DropDown_9 = uidropdown(app.Panel_10);
            app.DropDown_9.Items = {'微分熵'};
            app.DropDown_9.Value = '微分熵';
            app.Label_7 = uilabel(app.Panel_10);
            app.Label_7.Text = '特征';
            app.Label_8 = uilabel(app.Panel_10);
            app.Label_8.Text = '采样率';
            app.TextArea_4 = uitextarea(app.Panel_10);
            app.TextArea_4.Value = {'128'};
            app.Button_5 = uibutton(app.Panel_10, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'FeatureExtractionButton.png';
            app.Button_5.Text = '特征提取';
            app.Panel_14 = uipanel(app.Tab_3);
            app.Panel_14.Title = '特征结果显示';
            app.Panel_11 = uipanel(app.Panel_14);
            app.DropDown_10 = uidropdown(app.Panel_11);
            app.DropDown_10.Items = {};
            app.DropDown_10.ValueChangedFcn = createCallbackFcn(app, @DropDown_10ValueChanged, true);
            app.DropDown_10.Value = {};
            app.Label_4 = uilabel(app.Panel_11);
            app.Label_4.Text = '被试选择';
            app.Panel_12 = uipanel(app.Panel_14);
            app.DropDown_11 = uidropdown(app.Panel_12);
            app.DropDown_11.Items = {};
            app.DropDown_11.ValueChangedFcn = createCallbackFcn(app, @DropDown_11ValueChanged, true);
            app.DropDown_11.Value = {};
            app.Label_5 = uilabel(app.Panel_12);
            app.Label_5.Text = '实验选择';
            app.Panel_13 = uipanel(app.Panel_14);
            app.DropDown_12 = uidropdown(app.Panel_13);
            app.DropDown_12.Items = {};
            app.DropDown_12.ValueChangedFcn = createCallbackFcn(app, @DropDown_12ValueChanged, true);
            app.DropDown_12.Value = {};
            app.Label_6 = uilabel(app.Panel_13);
            app.Label_6.Text = '通道选择';
            app.ExportToMatFile_2 = uibutton(app.Panel_14, 'push');
            app.ExportToMatFile_2.ButtonPushedFcn = createCallbackFcn(app, @ExportToMatFile_2ButtonPushed, true);
            app.ExportToMatFile_2.Icon = 'cell-0-0-480.png';
            app.ExportToMatFile_2.Text = '结果保存';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '数据分类';
            app.Button_3 = uibutton(app.Tab_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'icon_large_intuser.png';
            app.Button_3.Text = '数据导入';
            app.TrainButton = uibutton(app.Tab_2, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Icon = '1_0FlvitTZnPKh8qkJ7UPLeQ.png';
            app.TrainButton.Text = 'Train';
            app.SplitDataPanel = uipanel(app.Tab_2);
            app.SplitDataPanel.Title = 'Split Data';
            app.TrainEditFieldLabel = uilabel(app.SplitDataPanel);
            app.TrainEditFieldLabel.Text = 'Train (%)';
            app.TrainEditField = uieditfield(app.SplitDataPanel, 'numeric');
            app.TrainEditField.Limits = [0 99];
            app.TrainEditField.Enable = 'off';
            app.TrainEditField.Value = 70;
            app.ValidationEditFieldLabel = uilabel(app.SplitDataPanel);
            app.ValidationEditFieldLabel.Text = 'Validation (%)';
            app.ValidationEditField = uieditfield(app.SplitDataPanel, 'numeric');
            app.ValidationEditField.Limits = [0 99];
            app.ValidationEditField.Enable = 'off';
            app.ValidationEditField.Value = 30;
            app.ButtonGroup = uibuttongroup(app.Tab_2);
            app.ButtonGroup.Title = '分类器选择';
            app.Tree = uitree(app.ButtonGroup);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeTextChangedFcn = createCallbackFcn(app, @TreeNodeTextChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = '机器学习';
            app.SVMNode = uitreenode(app.Node);
            app.SVMNode.Text = 'SVM';
            app.RFNode = uitreenode(app.Node);
            app.RFNode.Text = 'RF';
            app.XgboostNode = uitreenode(app.Node);
            app.XgboostNode.Text = 'Xgboost';
            app.Node_3 = uitreenode(app.Tree);
            app.Node_3.Text = '迁移学习';
            app.TCANode = uitreenode(app.Node_3);
            app.TCANode.Text = 'TCA';
            app.Label_2 = uilabel(app.ButtonGroup);
            app.Label_2.Text = '最小值';
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.Label_3 = uilabel(app.ButtonGroup);
            app.Label_3.Text = '最大值';
            app.EditField_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.Button_6 = uibutton(app.Tab_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'labelImport.png';
            app.Button_6.Text = '标签导入';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.DocumentationAboutthisAppButton = uibutton(app.HelpTab, 'push');
            app.DocumentationAboutthisAppButton.Icon = '057_-_Document_Info-512.png';
            app.DocumentationAboutthisAppButton.Text = 'Documentation About this App';
            app.MathWorksDocumentationTransferLearningButton = uibutton(app.HelpTab, 'push');
            app.MathWorksDocumentationTransferLearningButton.Icon = 'Product-documentation-icon.png';
            app.MathWorksDocumentationTransferLearningButton.Text = {'MathWorks Documentation'; '(Transfer Learning)'};
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.MATLABCodeGenerationButton = uibutton(app.ExportTab, 'push');
            app.MATLABCodeGenerationButton.Icon = 'code-icon-png-free147-4bf3-b748-6cea8c83ad71.png';
            app.MATLABCodeGenerationButton.Enable = 'off';
            app.MATLABCodeGenerationButton.Text = 'MATLAB Code Generation';
            app.ExportToWorkspaceButton = uibutton(app.ExportTab, 'push');
            app.ExportToWorkspaceButton.Icon = 'Matlab_Logo.png';
            app.ExportToWorkspaceButton.Enable = 'off';
            app.ExportToWorkspaceButton.Text = 'Export To Workspace';
            app.ExportToONNXButton = uibutton(app.ExportTab, 'push');
            app.ExportToONNXButton.Icon = 'mlogo.png';
            app.ExportToONNXButton.Enable = 'off';
            app.ExportToONNXButton.Text = 'Export To ONNX';
            app.GPUCoder = uibutton(app.ExportTab, 'push');
            app.GPUCoder.Icon = 'gpu-icon-gpu-pictograph-gpu-web-icon-gpu-icon-vector-gpu-icon-eps-gpu-icon-illustration_15267-4.jpg';
            app.GPUCoder.Enable = 'off';
            app.GPUCoder.Text = {'GPU'; ' Code Generation'};
            app.ExportToMatFile = uibutton(app.ExportTab, 'push');
            app.ExportToMatFile.Icon = 'cell-0-0-480.png';
            app.ExportToMatFile.Enable = 'off';
            app.ExportToMatFile.Text = 'Export To MAT File';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '分类结果';
            app.StatusofRunningLampLabel = uilabel(app.Panel_2);
            app.StatusofRunningLampLabel.Text = 'Status of Running';
            app.StatusofRunningLamp = uilamp(app.Panel_2);
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '时域波形显示';
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.Panel_3);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.InsightofImportedDataPanel = uipanel(app.UIFigure);
            app.InsightofImportedDataPanel.Title = 'Insight of Imported Data';
            app.SubjectIDLabel = uilabel(app.InsightofImportedDataPanel);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.TrailLabel = uilabel(app.InsightofImportedDataPanel);
            app.TrailLabel.Text = 'Trail ';
            app.ChannelLabel = uilabel(app.InsightofImportedDataPanel);
            app.ChannelLabel.Text = 'Channel';
            app.DurationLabel = uilabel(app.InsightofImportedDataPanel);
            app.DurationLabel.Text = 'Duration';
            app.Label_12 = uilabel(app.InsightofImportedDataPanel);
            app.Label_12.Text = '-';
            app.Label_13 = uilabel(app.InsightofImportedDataPanel);
            app.Label_13.Text = '-';
            app.Label_14 = uilabel(app.InsightofImportedDataPanel);
            app.Label_14.Text = '-';
            app.Label_15 = uilabel(app.InsightofImportedDataPanel);
            app.Label_15.Text = '-';
            app.StateLabel = uilabel(app.InsightofImportedDataPanel);
            app.StateLabel.Text = 'State';
            app.Label_16 = uilabel(app.InsightofImportedDataPanel);
            app.Label_16.Text = '-';
            app.ValidityofImportedDataLampLabel = uilabel(app.InsightofImportedDataPanel);
            app.ValidityofImportedDataLampLabel.Text = 'Validity of  Imported Data';
            app.ValidityofImportedDataLamp = uilamp(app.InsightofImportedDataPanel);
            app.InsightofPretrainedNeuralNetworkPanel = uipanel(app.UIFigure);
            app.InsightofPretrainedNeuralNetworkPanel.Title = 'Insight of Pre-trained Neural Network';
            app.FirstLayerInputSizeLabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.FirstLayerInputSizeLabel.Text = 'First Layer Input Size : ';
            app.TypeofNetworkLabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.TypeofNetworkLabel.Text = 'Type of Network :';
            app.NumberofLayerLabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.NumberofLayerLabel.Text = 'Number of Layer :';
            app.NumberofClassLabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.NumberofClassLabel.Text = 'Number of Class : ';
            app.Label_9 = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.Label_9.Text = '227   227    3';
            app.SeriesLabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.SeriesLabel.Text = 'Series';
            app.Label_10 = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.Label_10.Text = '25';
            app.Label_11 = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.Label_11.Text = '1000';
            app.Size = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.Size.Text = 'Size : ';
            app.ParametersMillionsLabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.ParametersMillionsLabel.Text = 'Parameters (Millions) :';
            app.SizeLabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.SizeLabel.Text = '227 MB';
            app.parameterlabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.parameterlabel.Text = '61.0';
            app.Name_neuralnetwork = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.Name_neuralnetwork.Text = 'Neural Network : ';
            app.neuralnetworklabel = uilabel(app.InsightofPretrainedNeuralNetworkPanel);
            app.neuralnetworklabel.Text = 'alexnet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end